classdef Crop_vessel_instance_segmentation_update < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SequenceNumberEditField       matlab.ui.control.NumericEditField
        SequenceNumberEditFieldLabel  matlab.ui.control.Label
        LoadTracingPathButton         matlab.ui.control.Button
        PointerLabel                  matlab.ui.control.Label
        TracingSaveButton             matlab.ui.control.Button
        StepEditField                 matlab.ui.control.NumericEditField
        StepEditFieldLabel            matlab.ui.control.Label
        LeftButton                    matlab.ui.control.Button
        RightButton                   matlab.ui.control.Button
        DownButton                    matlab.ui.control.Button
        UpButton                      matlab.ui.control.Button
        TracingOneStepButton          matlab.ui.control.Button
        RandomGenerateButton          matlab.ui.control.Button
        ImageSizeEditField            matlab.ui.control.NumericEditField
        ImageSizeEditFieldLabel       matlab.ui.control.Label
        SubjectNumberEditField        matlab.ui.control.NumericEditField
        SubjectNumberEditFieldLabel   matlab.ui.control.Label
        NLabel                        matlab.ui.control.Label
        InstanceNumberLabel           matlab.ui.control.Label
        VesselMaskCheckBox            matlab.ui.control.CheckBox
        CoordinatesLabel              matlab.ui.control.Label
        FileNumberEditField           matlab.ui.control.NumericEditField
        FileNumberLabel               matlab.ui.control.Label
        SaveButton                    matlab.ui.control.Button
        LoadButton                    matlab.ui.control.Button
        CropAxes_Original             matlab.ui.control.UIAxes
        CropAxes                      matlab.ui.control.UIAxes
        ImageAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        SaveDir
        BaseRetina % Intensity Input
        VesselInformation % All Vessel Tree: [row,column,layer] = size(app.VesselInformation);

        AlreadyCrop = 0; % whether have a crop

        Crop % cropped intensity image
        SemanticCrop % binary image
        InstanceCrop % multi-channel, save as mat?
        InstanceNumber

        Color = []; % mark different vessel tree

        GlobalShow % for display
        LocalShow % for display
        LocalShow_Original % for display
        
        Pixel_position % record the selected center position (pixel-wide)
        PositionList % all point of vessel skeleton
        Pointer = 1; % pointer of PositionList
        Sequence = 1;
    end
    
    
    methods (Access = private)
        
        function updateimage(app,imagefile)
            
            % For corn.tif, read the second image in the file
            try
                    app.BaseRetina = imread(imagefile);
                    if size(app.BaseRetina,3) > 3
                        app.BaseRetina = app.BaseRetina(:,:,1:3);
                    end
            catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end

            app.ImageAxes.Position = [60 100 size(app.BaseRetina,1) size(app.BaseRetina,2)];
            imagesc(app.ImageAxes,app.BaseRetina);
            app.GlobalShow = app.BaseRetina;

        end
        
        function Overlayvessel(app)
        % show color of overlay vessel tree masks
            [row,column,layer] = size(app.VesselInformation);
            vessel = reshape(app.VesselInformation,row*column,layer);
            base = reshape(app.BaseRetina,row*column,3);
           
            for i = 1:layer                
                base(vessel(:,i)~=0,1) = app.Color(i,1);
                base(vessel(:,i)~=0,2) = app.Color(i,2);
                base(vessel(:,i)~=0,3) = app.Color(i,3);
            end
            app.GlobalShow = reshape(base,row,column,3);
            imagesc(app.ImageAxes,app.GlobalShow);
            
        end
        
               
        function state = CropImage(app,coordinate,mode)
            state = false;
            croplength = app.ImageSizeEditField.Value;
            if mode == "coordinate"
                leftdown1 = [83,105]; 
                rightup1 = [620,660];
                column_ratio = size(app.BaseRetina,2)/abs(leftdown1(1)-rightup1(1));
                row_ratio = size(app.BaseRetina,1)/abs(leftdown1(2)-rightup1(2));
                if coordinate(2) >= leftdown1(2) + (croplength/2-1)/row_ratio && coordinate(2) <= rightup1(2) - (croplength/2)/row_ratio
                    if coordinate(1) >= leftdown1(1) + (croplength/2)/row_ratio && coordinate(1) <= rightup1(1) - (croplength/2-1)/column_ratio
                        pixel_map = [ceil((rightup1(2) - coordinate(2))*row_ratio)- croplength/2, ceil((rightup1(2) - coordinate(2))*row_ratio) + croplength/2-1,... 
                            ceil((coordinate(1) - leftdown1(1))*column_ratio)- croplength/2, ceil((coordinate(1) - leftdown1(1))*column_ratio) + croplength/2-1];
                        
                        app.Pixel_position = [ceil((rightup1(2) - coordinate(2))*row_ratio),ceil((coordinate(1) - leftdown1(1))*column_ratio)];
                        
                        app.Crop = app.BaseRetina(pixel_map(1):pixel_map(2),pixel_map(3):pixel_map(4),:);
                        if ~isempty(app.GlobalShow)
                            app.LocalShow = app.GlobalShow(pixel_map(1):pixel_map(2),pixel_map(3):pixel_map(4),:);
                            app.LocalShow_Original = app.BaseRetina(pixel_map(1):pixel_map(2),pixel_map(3):pixel_map(4),:);
                            imagesc(app.CropAxes,app.LocalShow);
                            imagesc(app.CropAxes_Original,app.LocalShow_Original);
                            app.AlreadyCrop = 1;
                            CalculateInstance(app,pixel_map);
                            state = true;
                        end
                    end
                end
            elseif mode == "pixel"
                pixel_map = [coordinate(1)-croplength/2, coordinate(1)+croplength/2-1, coordinate(2)-croplength/2, coordinate(2)+croplength/2-1];
                app.Pixel_position = coordinate;

                if pixel_map(1)>0 && pixel_map(2)<= size(app.BaseRetina,1) && pixel_map(3)>0 && pixel_map(4)<= size(app.BaseRetina,2)
                    
                    app.Crop = app.BaseRetina(pixel_map(1):pixel_map(2),pixel_map(3):pixel_map(4),:);
                    if ~isempty(app.GlobalShow)
                        app.LocalShow = app.GlobalShow(pixel_map(1):pixel_map(2),pixel_map(3):pixel_map(4),:);
                        app.LocalShow_Original = app.BaseRetina(pixel_map(1):pixel_map(2),pixel_map(3):pixel_map(4),:);
                        imagesc(app.CropAxes,app.LocalShow);
                        imagesc(app.CropAxes_Original,app.LocalShow_Original);
                        app.AlreadyCrop = 1;
                        CalculateInstance(app,pixel_map);
                        state = true;
                    end
                end
            end
        end


        function CalculateInstance(app, pixel_map)
            % calculate instance number
            layer = size(app.VesselInformation,3);
            layer_record = []; 
            for i = 1:layer
                if sum(app.VesselInformation(pixel_map(1):pixel_map(2),pixel_map(3):pixel_map(4),i),"all")
                    layer_record = [layer_record, i];
                end
            end
            if ~isnan(layer_record)
                count = 0;
                instance = zeros(pixel_map(2)-pixel_map(1)+1,pixel_map(4)-pixel_map(3)+1,3*length(layer_record));
                
                se = strel("rectangle",[2 2]);
                semantic = zeros(pixel_map(2)-pixel_map(1)+1,pixel_map(4)-pixel_map(3)+1);
                for j = 1:length(layer_record)
                    target = app.VesselInformation(pixel_map(1):pixel_map(2),pixel_map(3):pixel_map(4),layer_record(j));
                    % for semantic segmentation
                    semantic = target + semantic;

                    % for instance segmentation
                    target_dilate = imdilate(target,se);  
                    CC = bwconncomp(target_dilate);
                    for k = 1:CC.NumObjects
                        unconnected = zeros((pixel_map(2)-pixel_map(1)+1)*(pixel_map(4)-pixel_map(3)+1),1);
                        unconnected(CC.PixelIdxList{k}) = 1;
                        unconnected = reshape(unconnected,pixel_map(2)-pixel_map(1)+1,pixel_map(4)-pixel_map(3)+1);
                        target_unconnected = target.*unconnected;

                        count = count + 1;
                        instance(:,:,count) = target_unconnected;
                    end
                end
                app.NLabel.Text = string(count);
                app.InstanceNumber = count;
                app.InstanceCrop = instance(:,:,1:count);
                
                semantic(semantic>0)=1;
                app.SemanticCrop = semantic;
            else
                app.NLabel.Text = "none"; 
            end
        end
        
        function Tracing(app)
            layer = size(app.VesselInformation,3);
            row_whole = [];
            column_whole = [];
            for i = 1:layer
                skeleton = bwskel(logical(app.VesselInformation(:,:,i)));
                [row,column] = find(skeleton==1);
                row_whole = [row_whole;row];
                column_whole = [column_whole;column];
            end
            app.PositionList = [row_whole,column_whole];
            app.Pointer = 1;
        end
        
        function Saving(app)
            no = string(app.FileNumberEditField.Value);
            if isempty(app.SaveDir)
                filename = uigetdir();
                dir = strcat(filename, '\');
                app.SaveDir = dir;
            end
            imwrite(app.Crop, app.SaveDir + no + '_I.tif')
            imwrite(app.SemanticCrop, app.SaveDir + no + '_Semantic.tif')
            for i = 1:size(app.InstanceCrop,3)
                imwrite(app.InstanceCrop(:,:,i), app.SaveDir + no + '_Instance_'+ string(i) + '.tif')
            end
            writematrix([app.FileNumberEditField.Value app.InstanceNumber app.SubjectNumberEditField.Value app.SequenceNumberEditField.Value app.Pixel_position],strcat(app.SaveDir,'\test.txt'),'WriteMode','append','Delimiter','space')
            app.FileNumberEditField.Value = app.FileNumberEditField.Value + 1;

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            app.CropAxes.Visible = 'off';
            app.CropAxes.Colormap = gray(256);
            axis(app.CropAxes, 'image');
            app.CropAxes_Original.Visible = 'off';
            app.CropAxes_Original.Colormap = gray(256);
            axis(app.CropAxes_Original, 'image');            
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
               
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);

            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                fname = [p f];

                split_string = split(p, '\');
                folor_string = split_string{end-1};
                subject_string = split(folor_string, ' ');
                app.SubjectNumberEditField.Value = str2double(subject_string{1});

                fileFolder=fullfile(p);
                dirOutput=dir(fullfile(fileFolder,'*.tif'));
                fileNames={dirOutput.name};
                template = imread([p fileNames{1}]);
                %disp(size(template))
                layer = zeros(size(template,1),size(template,2),length(fileNames)-1);
                for i = 1:length(fileNames)
                    if ~strcmp([p fileNames{i}],fname)
                    ii = imread([p fileNames{i}]);
                    layer(:,:,i) = ii(:,:,1);
                    end
                end
                app.VesselInformation = layer;
                app.Color = randi([0 255],size(layer,3),3);
               updateimage(app, fname);
               Tracing(app);
            end
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
             Coordinates = event.Source.CurrentPoint;
             app.CoordinatesLabel.Text = string(Coordinates);   
             CropImage(app,Coordinates,"coordinate");
        end

        % Value changed function: VesselMaskCheckBox
        function VesselMaskCheckBoxValueChanged(app, event)
            value = app.VesselMaskCheckBox.Value;
            if value
                Overlayvessel(app);
                if(app.AlreadyCrop)
                    imagesc(app.CropAxes,app.LocalShow);
                end
            else
                imagesc(app.ImageAxes,app.BaseRetina);
                if(app.AlreadyCrop)
                    imagesc(app.CropAxes,app.Crop);
                end
            end
        end

        % Button pushed function: RandomGenerateButton
        function RandomGenerateButtonPushed(app, event)
            
            leftdown1 = [83,105]; 
            rightup1 = [620,660];
            croplength = app.ImageSizeEditField.Value;
            column_ratio = size(app.BaseRetina,2)/abs(leftdown1(1)-rightup1(1));
            row_ratio = size(app.BaseRetina,1)/abs(leftdown1(2)-rightup1(2));
            a = ceil(leftdown1(1) + (croplength/2)/column_ratio); % 83
            b = floor(rightup1(1) - (croplength/2-1)/column_ratio); % 529
            c = ceil(leftdown1(2) + (croplength/2-1)/row_ratio); % 196
            d = floor(rightup1(2) - (croplength/2)/row_ratio); % 660
            Coordinates = [randi([a,b],1,1),randi([c,d],1,1)];
            app.CoordinatesLabel.Text = string(Coordinates);   
            CropImage(app,Coordinates,"coordinate");
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            Coordinates = app.Pixel_position;
            Coordinates(1) = Coordinates(1)-1;
            CropImage(app,Coordinates,"pixel");
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            Coordinates = app.Pixel_position;
            Coordinates(1) = Coordinates(1)+1;
            CropImage(app,Coordinates,"pixel");
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            Coordinates = app.Pixel_position;
            Coordinates(2) = Coordinates(2)-1;
            CropImage(app,Coordinates,"pixel");
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            Coordinates = app.Pixel_position;
            Coordinates(2) = Coordinates(2)+1;
            CropImage(app,Coordinates,"pixel");            
        end

        % Button pushed function: TracingOneStepButton
        function TracingOneStepButtonPushed(app, event)
            step = app.StepEditField.Value;
            if app.Pointer > length(app.PositionList{app.Sequence,1})
                app.Pointer = app.Pointer - length(app.PositionList{app.Sequence,1});
                app.Sequence = app.Sequence + 1;
                app.SequenceNumberEditField.Value = app.SequenceNumberEditField.Value + 1;
            end
            Coordinates = app.PositionList{app.Sequence,1}(app.Pointer,:);
            app.Pointer = app.Pointer + step;
            CropImage(app,Coordinates,"pixel");  
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            Saving(app);
        end

        % Button pushed function: TracingSaveButton
        function TracingSaveButtonPushed(app, event)
            step = app.StepEditField.Value;
            while app.Sequence <= max(size(app.PositionList,1),size(app.PositionList,2))
                if app.Pointer > length(app.PositionList{app.Sequence,1})
                    app.Pointer = 1;
                    app.Sequence = app.Sequence + 1;
                    if app.Sequence > max(size(app.PositionList,1),size(app.PositionList,2))
                        break
                    end
                    app.SequenceNumberEditField.Value = app.SequenceNumberEditField.Value + 1;
                end
                app.PointerLabel.Text = string(app.Pointer);   
                Coordinates = app.PositionList{app.Sequence,1}(app.Pointer,:);
                state = CropImage(app,Coordinates,"pixel");  
                if state == true
                    Saving(app);
                end
                app.Pointer = app.Pointer + step;
            end
        end

        % Button pushed function: LoadTracingPathButton
        function LoadTracingPathButtonPushed(app, event)
            filterspec = {'D:\Yi Lab\dataset\TracingMapSource\*.txt'};
            [f, p] = uigetfile(filterspec);

            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                fname = [p f];
                I = readcell(fname);
            

                for i = 1:length(I)
                    I{i,1} = strsplit(I{i,1},' ');
                end

                K = cell(length(I)/2,1);
                for i = 1:length(I)/2
                    K{i,1} = str2double(vertcat(I{2*i-1:2*i,1}))';
                    
                end 
                app.PositionList = K;
                app.Pointer = 1;
                app.Sequence = 1;
                app.SequenceNumberEditField.Value = 0;

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Crop Image';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = '';
            app.ImageAxes.YTick = [];
            app.CropAxes = uiaxes(app.UIFigure);
            app.CropAxes.XTick = [];
            app.CropAxes.XTickLabel = '';
            app.CropAxes.YTick = [];
            app.CropAxes_Original = uiaxes(app.UIFigure);
            app.CropAxes_Original.XTick = [];
            app.CropAxes_Original.XTickLabel = '';
            app.CropAxes_Original.YTick = [];
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = '1. Load Image';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save Current Data';
            app.FileNumberLabel = uilabel(app.UIFigure);
            app.FileNumberLabel.Text = 'File Number';
            app.FileNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.CoordinatesLabel = uilabel(app.UIFigure);
            app.CoordinatesLabel.Text = 'Coordinates';
            app.VesselMaskCheckBox = uicheckbox(app.UIFigure);
            app.VesselMaskCheckBox.ValueChangedFcn = createCallbackFcn(app, @VesselMaskCheckBoxValueChanged, true);
            app.VesselMaskCheckBox.Text = ' 2. Vessel Mask';
            app.InstanceNumberLabel = uilabel(app.UIFigure);
            app.InstanceNumberLabel.Text = 'Instance Number    =';
            app.NLabel = uilabel(app.UIFigure);
            app.NLabel.Text = 'N';
            app.SubjectNumberEditFieldLabel = uilabel(app.UIFigure);
            app.SubjectNumberEditFieldLabel.Text = 'Subject Number';
            app.SubjectNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImageSizeEditFieldLabel = uilabel(app.UIFigure);
            app.ImageSizeEditFieldLabel.Text = 'Image Size';
            app.ImageSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImageSizeEditField.Value = 96;
            app.RandomGenerateButton = uibutton(app.UIFigure, 'push');
            app.RandomGenerateButton.ButtonPushedFcn = createCallbackFcn(app, @RandomGenerateButtonPushed, true);
            app.RandomGenerateButton.Text = 'Random Generate';
            app.TracingOneStepButton = uibutton(app.UIFigure, 'push');
            app.TracingOneStepButton.ButtonPushedFcn = createCallbackFcn(app, @TracingOneStepButtonPushed, true);
            app.TracingOneStepButton.Text = 'Tracing One Step';
            app.UpButton = uibutton(app.UIFigure, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.DownButton = uibutton(app.UIFigure, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.RightButton = uibutton(app.UIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.LeftButton = uibutton(app.UIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.StepEditFieldLabel = uilabel(app.UIFigure);
            app.StepEditFieldLabel.Text = 'Step';
            app.StepEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepEditField.Value = 10;
            app.TracingSaveButton = uibutton(app.UIFigure, 'push');
            app.TracingSaveButton.ButtonPushedFcn = createCallbackFcn(app, @TracingSaveButtonPushed, true);
            app.TracingSaveButton.Text = '4. Save Tracing Data';
            app.PointerLabel = uilabel(app.UIFigure);
            app.PointerLabel.Text = 'Pointer';
            app.LoadTracingPathButton = uibutton(app.UIFigure, 'push');
            app.LoadTracingPathButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTracingPathButtonPushed, true);
            app.LoadTracingPathButton.Text = '3. Load Tracing Path';
            app.SequenceNumberEditFieldLabel = uilabel(app.UIFigure);
            app.SequenceNumberEditFieldLabel.Text = 'Sequence Number';
            app.SequenceNumberEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Crop_vessel_instance_segmentation_update

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end